classdef Gear_model < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MainUIFigure                   matlab.ui.Figure
        MainGridLayout                 matlab.ui.container.GridLayout
        MainTabGroup                   matlab.ui.container.TabGroup
        HomeTab                        matlab.ui.container.Tab
        ProfileTab                     matlab.ui.container.Tab
        ProfileGridLayout              matlab.ui.container.GridLayout
        ExportPanel                    matlab.ui.container.Panel
        ExportGridLayout               matlab.ui.container.GridLayout
        GridLayout6                    matlab.ui.container.GridLayout
        CancelAllProfilesButton        matlab.ui.control.Button
        CancelProfileButton            matlab.ui.control.Button
        DrawProfileButton              matlab.ui.control.Button
        NastaveniezobrazeniaPanel      matlab.ui.container.Panel
        GridLayout7                    matlab.ui.container.GridLayout
        GridLayout14                   matlab.ui.container.GridLayout
        VznamnkruniceButton            matlab.ui.control.Button
        OsiCheckBox                    matlab.ui.control.CheckBox
        MriekaCheckBox                 matlab.ui.control.CheckBox
        GridLayout16                   matlab.ui.container.GridLayout
        HrbkaSpinner                   matlab.ui.control.Spinner
        HrbkaSpinnerLabel              matlab.ui.control.Label
        GridLayout17                   matlab.ui.container.GridLayout
        FarbaColorPicker               matlab.ui.control.ColorPicker
        FarbaColorPickerLabel          matlab.ui.control.Label
        GridLayout18                   matlab.ui.container.GridLayout
        tlDropDown                     matlab.ui.control.DropDown
        tlDropDownLabel                matlab.ui.control.Label
        iaryLabel                      matlab.ui.control.Label
        GrafLabel                      matlab.ui.control.Label
        GridLayout8                    matlab.ui.container.GridLayout
        LockButton                     matlab.ui.control.Button
        UzamknutiezobrazeniadopoiatkuLabel  matlab.ui.control.Label
        GridLayout2                    matlab.ui.container.GridLayout
        ButtonGroup_2                  matlab.ui.container.ButtonGroup
        JednotlivprofilButton          matlab.ui.control.RadioButton
        ParametricksekvenciaprofilovButton  matlab.ui.control.RadioButton
        GridLayout3                    matlab.ui.container.GridLayout
        NumberOfProfilesSpinner        matlab.ui.control.Spinner
        NumberOfProfilesSpinnerLabel   matlab.ui.control.Label
        MonostigenerovaniaLabel        matlab.ui.control.Label
        NastavenieozubeniaPanel        matlab.ui.container.Panel
        GridLayout                     matlab.ui.container.GridLayout
        GridLayout5                    matlab.ui.container.GridLayout
        ModuleEditField_1              matlab.ui.control.NumericEditField
        ModuleEditField_2              matlab.ui.control.NumericEditField
        NumberOfTeethEditField_1       matlab.ui.control.NumericEditField
        NumberOfTeethEditField_2       matlab.ui.control.NumericEditField
        ProfileShiftCoefficientEditField_1  matlab.ui.control.NumericEditField
        ProfileShiftCoefficientEditField_2  matlab.ui.control.NumericEditField
        Option1EditField_1             matlab.ui.control.NumericEditField
        Option1EditField_2             matlab.ui.control.NumericEditField
        Option2EditField_1             matlab.ui.control.NumericEditField
        Option2EditField_2             matlab.ui.control.NumericEditField
        ParameterChoiceButtonGroup     matlab.ui.container.ButtonGroup
        ModuleButton                   matlab.ui.control.RadioButton
        NumberOfTeethButton            matlab.ui.control.RadioButton
        ProfileShiftCoefficientButton  matlab.ui.control.RadioButton
        Option1Button                  matlab.ui.control.RadioButton
        Option2Button                  matlab.ui.control.RadioButton
        ModuleLabel                    matlab.ui.control.Label
        ModuleSymbolicLabel            matlab.ui.control.Label
        NumberOfTeethLabel             matlab.ui.control.Label
        NumberOfTeethSymbolicLabel     matlab.ui.control.Label
        ProfileShiftCoefficientLabel   matlab.ui.control.Label
        ProfileShiftCoefficientSymbolicLabel  matlab.ui.control.Label
        Option1Label                   matlab.ui.control.Label
        Option1SymbolicLabel           matlab.ui.control.Label
        Option2Label                   matlab.ui.control.Label
        Option2SymbolicLabel           matlab.ui.control.Label
        GridLayout4                    matlab.ui.container.GridLayout
        DodatoneParametreButton        matlab.ui.control.Button
        ButtonGroup                    matlab.ui.container.ButtonGroup
        EvolventnButton                matlab.ui.control.RadioButton
        CykloidnButton                 matlab.ui.control.RadioButton
        TypozubeniaLabel               matlab.ui.control.Label
        ProfileText                    matlab.ui.control.Label
        AnimationTab                   matlab.ui.container.Tab
        AnimationGridLayout            matlab.ui.container.GridLayout
        DisplayButtonsGridLayout       matlab.ui.container.GridLayout
        StartPauseButton               matlab.ui.control.Button
        DisplayButton                  matlab.ui.control.Button
        CancelButton                   matlab.ui.control.Button
        AnimationSettingPanel          matlab.ui.container.Panel
        AnimationSettingGridLayout     matlab.ui.container.GridLayout
        LowerAnimationSettingGridLayout  matlab.ui.container.GridLayout
        UpperAnimationSettingGridLayout  matlab.ui.container.GridLayout
        ToothingSettingPanel           matlab.ui.container.Panel
        ExportSettingPanel             matlab.ui.container.Panel
    end


    % ------------------------------------------------------------------------
    % Author: Richard Timko
    % Copyright (c) 2026 Richard Timko
    %
    % This file is part of Gear Modeller and is released under the MIT License.
    % See the LICENSE file in the project root directory for full details.
    % ------------------------------------------------------------------------

    properties
        % Figures
        OutputFigure matlab.ui.Figure % Graphical output
        ProfileCirclesFigure matlab.ui.Figure % Dialog figure for activating circles in plot

        % Axes
        AxisOutput matlab.graphics.axis.Axes % Property to save axes for output figure

        appFolder
    end

    properties % Utils
        Gen % Auxiliary object for tooth profile generator

        LanguageUtils % Auxiliary object for language management

        % Home Tab
        HomeUtils % Auxiliary object concerning the Home tab

        % Profile Tab
        ProfileTabManagerUtils % Auxiliary object for managing the Profile Tab logics
        AnimationTabUtils % Auxiliary object for managing the Animation Tab (creation and logics)
        FurtherParametersUtils % Auxiliary object for managing the parameter dialog figure from the Profile Tab
        CirclesUtils % Auxiliary object for managing the circle activation dialog figure from the Profile Tab
        GraphicalAdditions % Auxiliary object for managing the graphical additions activation dialog figure from the Animation Tab
        ExportUtils % Auxiliary object for managing the export section from the Profile Tab
        AnimationControl % Auxiliary object for managing the animation inside occuring iniside the figure
        AnimationExport % Auxiliary object for managing the export section from the Animation Tab
        AnimationParameters % Auxiliary object for managing the parameter dialog figure from the Animation Tab

        ContextMenuModuleUtils1 % Auxiliary object for generating a context menu to choose module from standard (Profile Tab) - first value
        ContextMenuModuleUtils2 % Auxiliary object for generating a context menu to choose module from standard (Profile Tab) - second value
        ContextMenuPlotColourUtils % Auxiliary object for generating a context menu to activate/deactivate the colour picker (Profile Tab)
    end

    methods
        function CancelAllFun(app)
            while app.ProfileTabManagerUtils.ProfileCounter > 0
                delete(app.ProfileTabManagerUtils.ProfilePlot(app.ProfileTabManagerUtils.ProfileCounter));
                if size(app.CirclesUtils.CirclePlot,2) ~= 1
                    delete(app.CirclesUtils.CirclePlot(:,app.ProfileTabManagerUtils.ProfileCounter));
                end
                app.ProfileTabManagerUtils.ProfileCounter = app.ProfileTabManagerUtils.ProfileCounter - 1;
            end

            app.ExportUtils.ExportSpinner.Limits = [0 0];
            app.ExportUtils.ExportSpinner.Value = 0;
            app.ExportUtils.ExportButton.Enable = 0;

            delete(findobj(app.AxisOutput,'Type','Line'));

            set(app.AxisOutput,"Visible",0,"HandleVisibility","off");
            app.HomeUtils.FigureText.Visible = "on";

            app.AnimationControl.start_state = 0;
            app.AnimationExport.video_export_state = 0;
            app.AnimationControl.AxisAnimation.Visible = 0;
            delete( findobj(app.AnimationControl.AxisAnimation, 'Type', 'line') );
            delete( findobj(app.AnimationControl.AxisAnimation, 'Type', 'patch') );
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.appFolder = fileparts(mfilename('fullpath'));
            
            if isgraphics(figure(1))
                close(figure(1));
            end

            % Auxiliary objects
            app.LanguageUtils = Utils.languageUtils;
            app.HomeUtils = Utils.homeUtils(app);

            app.GraphicalAdditions = Utils.AnimationTab.animationGraphicalAdditions(app);
            app.AnimationTabUtils = Utils.AnimationTab.animationTabUtils(app);
            app.AnimationControl = Utils.AnimationTab.animationControl(app.AnimationTabUtils,app.OutputFigure);
            app.AnimationExport = Utils.AnimationTab.animationExport(app);
            app.AnimationParameters = Utils.AnimationTab.animationTabParameters;

            app.ExportUtils = Utils.ProfileTab.exportUtils(app);
            app.ProfileTabManagerUtils = Utils.ProfileTab.profileTabManagerUtils(app);
            app.FurtherParametersUtils = Utils.ProfileTab.furtherParametersUtils;
            app.CirclesUtils = Utils.ProfileTab.circlesUtils(app);
            
            app.LanguageUtils = languageSetUp(app.LanguageUtils,app);
            
            profileSettingFunction(app.ProfileTabManagerUtils,app);
            app.ContextMenuModuleUtils1 = Utils.ProfileTab.contextMenuModuleUtils(app,app.ModuleEditField_1); app.ModuleEditField_1.ContextMenu = app.ContextMenuModuleUtils1.ContextMenu;
            app.ContextMenuModuleUtils2 = Utils.ProfileTab.contextMenuModuleUtils(app,app.ModuleEditField_2); app.ModuleEditField_2.ContextMenu = app.ContextMenuModuleUtils2.ContextMenu;
            app.ContextMenuPlotColourUtils = Utils.ProfileTab.contextMenuPlotColourUtils(app.MainUIFigure, app.LanguageUtils.LanguageChoice, app.FarbaColorPicker, app.FarbaColorPickerLabel);
        end

        % Close request function: MainUIFigure
        function MainUIFigureCloseRequest(app, event)
            % 1. Close auxiliary figures
            secondaryFigs = {app.OutputFigure, app.FurtherParametersUtils.F, ...
                app.CirclesUtils.F, app.GraphicalAdditions.F, ...
                app.AnimationParameters.F};

            for i = 1:length(secondaryFigs)
                if isvalid(secondaryFigs{i})
                    delete(secondaryFigs{i});
                end
            end

            if ~isdeployed
                % 2. Run your persistent variable cleanup
                % Clear persistent memory from your functions
                fileList = dir(fullfile('.', '**', '*.m'));
                [~, names] = cellfun(@fileparts, {fileList.name}, 'UniformOutput', false);

                if ~isempty(names)
                    clear(names{:});
                end
                % Note: DO NOT call delete(app) here anymore,
                % as we call it in the CloseRequest function above.

                % 3. Trigger the built-in delete method
                % This executes the "Access = public" delete method you showed me,
                % which safely handles delete(app.MainUIFigure).
            end

            delete(app);
        end

        % Selection changed function: ButtonGroup_2
        function ButtonGroup_2SelectionChanged(app, event)
            app.ProfileTabManagerUtils.profileSettingFunction(app);

            if app.JednotlivprofilButton.Value == 1
                app.ModuleEditField_1.Limits(2) = Inf;
                app.NumberOfTeethEditField_1.Limits(2) = Inf;
                app.ProfileShiftCoefficientEditField_1.Limits(2) = Inf;
                app.Option1EditField_1.Limits(2) = Inf;
                
            elseif app.ParametricksekvenciaprofilovButton.Value == 1
                app.ModuleEditField_2.Limits(1) = app.ModuleEditField_1.Value;
                app.NumberOfTeethEditField_2.Limits(1) = app.NumberOfTeethEditField_1.Value;
                app.ProfileShiftCoefficientEditField_2.Limits(1) = app.ProfileShiftCoefficientEditField_1.Value;
                app.Option1EditField_2.Limits(1) = app.Option1EditField_1.Value;

                app.Option2EditField_2.Limits(1) = app.Option2EditField_1.Value;
                
                if isempty(app.ModuleEditField_2.Value) == 0
                    app.ModuleEditField_1.Limits(2) = app.ModuleEditField_2.Value;
                end
                
                if isempty(app.NumberOfTeethEditField_2.Value) == 0
                    app.NumberOfTeethEditField_1.Limits(2) = app.NumberOfTeethEditField_2.Value;
                end
                
                if isempty(app.ProfileShiftCoefficientEditField_2.Value) == 0
                    app.ProfileShiftCoefficientEditField_1.Limits(2) = app.ProfileShiftCoefficientEditField_2.Value;
                end

                if isempty(app.Option1EditField_2.Value) == 0
                    app.Option1EditField_1.Limits(2) = app.Option1EditField_2.Value;
                end

                if isempty(app.Option2EditField_2.Value) == 0
                    app.Option2EditField_1.Limits(2) = app.Option2EditField_2.Value;
                end
            end
        end

        % Selection changed function: ParameterChoiceButtonGroup
        function ParameterChoiceButtonGroupSelectionChanged(app, event)
            profileSettingParameterFunction(app.ProfileTabManagerUtils,app)
        end

        % Value changed function: ModuleEditField_2
        function ModuleEditField_2ValueChanged(app, event)
            app.ModuleEditField_1.Limits(2) = app.ModuleEditField_2.Value;
            app.ModuleEditField_2.AllowEmpty = "off";
        end

        % Value changed function: Option1EditField_2
        function Option1EditField_2ValueChanged(app, event)
            app.Option1EditField_1.Limits(2) = app.Option1EditField_2.Value;
            app.Option1EditField_2.AllowEmpty = "off";
        end

        % Value changed function: NumberOfTeethEditField_2
        function NumberOfTeethEditField_2ValueChanged(app, event)
            app.NumberOfTeethEditField_1.Limits(2) = app.NumberOfTeethEditField_2.Value;
            app.NumberOfTeethEditField_2.AllowEmpty = "off";
        end

        % Value changed function: ProfileShiftCoefficientEditField_2
        function ProfileShiftCoefficientEditField_2ValueChanged(app, event)
            app.ProfileShiftCoefficientEditField_1.Limits(2) = app.ProfileShiftCoefficientEditField_2.Value;
            app.ProfileShiftCoefficientEditField_2.AllowEmpty = "off";
        end

        % Button pushed function: DrawProfileButton
        function DrawProfileButtonPushed(app, event)
            Utils.ProfileTab.drawFunction(app);
        end

        % Button pushed function: CancelProfileButton
        function CancelProfileButtonPushed(app, event)
            if app.AnimationControl.AnimationChecker == 1
                delete(findobj(app.AxisOutput,'Type','Line'));
            end
            
            if app.ProfileTabManagerUtils.ProfileCounter > 0
                delete(app.ProfileTabManagerUtils.ProfilePlot(app.ProfileTabManagerUtils.ProfileCounter));
                delete(app.CirclesUtils.CirclePlot(:,app.ProfileTabManagerUtils.ProfileCounter));
                app.ProfileTabManagerUtils.ProfileCounter = app.ProfileTabManagerUtils.ProfileCounter - 1;
                if app.ProfileTabManagerUtils.ProfileCounter == 0
                    app.ExportUtils.ExportSpinner.Limits = [0 0];
                else
                    app.ExportUtils.ExportSpinner.Limits = [1 double(app.ProfileTabManagerUtils.ProfileCounter)];
                end
                app.ExportUtils.ExportSpinner.Value = double(app.ProfileTabManagerUtils.ProfileCounter);
            end

            if app.ProfileTabManagerUtils.ProfileCounter == 0
                set(app.AxisOutput,"Visible",0,"HandleVisibility","off");
                app.HomeUtils.FigureText.Visible = "on";

                app.ExportUtils.ExportSpinner.Limits = [0 0];
                app.ExportUtils.ExportSpinner.Value = 0;
                app.ExportUtils.ExportButton.Enable = 0;
            end

            delete( findobj(app.AnimationControl.AxisAnimation, 'Type', 'patch') );
        end

        % Button pushed function: CancelAllProfilesButton, CancelButton
        function CancelAllProfilesButtonPushed(app, event)
            CancelAllFun(app)
        end

        % Value changed function: ModuleEditField_1
        function ModuleEditField_1ValueChanged(app, event)
            app.ModuleEditField_2.Limits(1) = app.ModuleEditField_1.Value;
            app.Option2EditField_1.Limits(1) = (1.25-app.ProfileShiftCoefficientEditField_1.Value)*app.ModuleEditField_1.Value/2;
            app.Option2EditField_1.Limits(2) = (app.ModuleEditField_1.Value*app.NumberOfTeethEditField_1.Value - (1.25-app.ProfileShiftCoefficientEditField_1.Value)*app.ModuleEditField_1.Value)/2;
            app.Option2EditField_2.Limits(2) = app.Option2EditField_1.Limits(2);
        end

        % Value changed function: Option1EditField_1
        function Option1EditField_1ValueChanged(app, event)
            app.Option1EditField_2.Limits(1) = app.Option1EditField_1.Value;
        end

        % Value changed function: NumberOfTeethEditField_1
        function NumberOfTeethEditField_1ValueChanged(app, event)
            app.NumberOfTeethEditField_2.Limits(1) = app.NumberOfTeethEditField_1.Value;
        end

        % Value changed function: ProfileShiftCoefficientEditField_1
        function ProfileShiftCoefficientEditField_1ValueChanged(app, event)
            app.Option2EditField_1.Limits(1) = (1.25-app.ProfileShiftCoefficientEditField_1.Value)*app.ModuleEditField_1.Value/2;
            app.Option2EditField_1.Limits(2) = (app.ModuleEditField_1.Value*app.NumberOfTeethEditField_1.Value - (1.25-app.ProfileShiftCoefficientEditField_1.Value)*app.ModuleEditField_1.Value)/2;
            app.Option2EditField_2.Limits(2) = app.Option2EditField_1.Limits(2);
        end

        % Button pushed function: LockButton
        function LockButtonPushed(app, event)
            if app.ProfileTabManagerUtils.LockChecker == false
                app.ProfileTabManagerUtils.LockChecker = true;
                app.LockButton.Icon = fullfile(app.appFolder,"Images","Lock_closed.png");
            elseif app.ProfileTabManagerUtils.LockChecker == true
                app.ProfileTabManagerUtils.LockChecker = false;
                app.LockButton.Icon = fullfile(app.appFolder,"Images","Lock_open.png");
            end
        end

        % Button pushed function: DodatoneParametreButton
        function DodatoneParametreButtonPushed(app, event)
            if isgraphics(app.FurtherParametersUtils.F)
                close(app.FurtherParametersUtils.F)
            else
                app.FurtherParametersUtils = create(app.FurtherParametersUtils,app);
            end
        end

        % Value changed function: OsiCheckBox
        function OsiCheckBoxValueChanged(app, event)
            if app.ProfileTabManagerUtils.ProfileCounter >= 1
                if app.OsiCheckBox.Value
                    axis(app.AxisOutput,"on")
                else
                    axis(app.AxisOutput,"off")
                end
            end
        end

        % Value changed function: MriekaCheckBox
        function MriekaCheckBoxValueChanged(app, event)
            if app.ProfileTabManagerUtils.ProfileCounter >= 1
                if app.MriekaCheckBox.Value
                    grid(app.AxisOutput,"on")
                else
                    grid(app.AxisOutput,"off")
                end
            end
        end

        % Callback function: FarbaColorPicker
        function FarbaColorPickerValueChanged(app, event)
            if app.ProfileTabManagerUtils.ProfileCounter >= 1 && app.FarbaColorPicker.Enable == "on"
                set(app.ProfileTabManagerUtils.ProfilePlot(app.ProfileTabManagerUtils.ProfileCounter),"Color",app.FarbaColorPicker.Value);
            end
        end

        % Value changed function: HrbkaSpinner
        function HrbkaSpinnerValueChanged(app, event)
            if app.ProfileTabManagerUtils.ProfileCounter >= 1
                set(app.ProfileTabManagerUtils.ProfilePlot(app.ProfileTabManagerUtils.ProfileCounter),"LineWidth",app.HrbkaSpinner.Value);
            end
        end

        % Value changed function: tlDropDown
        function tlDropDownValueChanged(app, event)
            if app.ProfileTabManagerUtils.ProfileCounter >= 1
                set(app.ProfileTabManagerUtils.ProfilePlot(app.ProfileTabManagerUtils.ProfileCounter),"LineStyle",Utils.ProfileTab.profileLineStyleFunction(app.tlDropDown.ValueIndex));
            end
        end

        % Button pushed function: VznamnkruniceButton
        function VznamnkruniceButtonPushed(app, event)
            if isgraphics(app.CirclesUtils.F)
                close(app.CirclesUtils.F)
            else
                app.CirclesUtils = create(app.CirclesUtils,app);
            end
        end

        % Button pushed function: DisplayButton
        function DisplayButtonPushed(app, event)
            app.AnimationControl.AxisAnimation.Visible = 1;
            displayFun(app.AnimationControl,app);
        end

        % Button pushed function: StartPauseButton
        function StartPauseButtonPushed(app, event)
            startAnimation(app.AnimationControl,app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.MainUIFigure = uifigure('Visible', 'off');
            app.MainUIFigure.Icon = fullfile(pathToMLAPP, 'Images', 'VUT_logo.png');
            app.MainUIFigure.CloseRequestFcn = createCallbackFcn(app, @MainUIFigureCloseRequest, true);
            app.MainGridLayout = uigridlayout(app.MainUIFigure);
            app.MainTabGroup = uitabgroup(app.MainGridLayout);
            app.HomeTab = uitab(app.MainTabGroup);
            app.ProfileTab = uitab(app.MainTabGroup);
            app.ProfileGridLayout = uigridlayout(app.ProfileTab);
            app.ProfileText = uilabel(app.ProfileGridLayout);
            app.ProfileText.Text = 'Label2';
            app.NastavenieozubeniaPanel = uipanel(app.ProfileGridLayout);
            app.NastavenieozubeniaPanel.Title = 'Nastavenie ozubenia';
            app.GridLayout = uigridlayout(app.NastavenieozubeniaPanel);
            app.GridLayout4 = uigridlayout(app.GridLayout);
            app.TypozubeniaLabel = uilabel(app.GridLayout4);
            app.TypozubeniaLabel.Text = 'Typ ozubenia:';
            app.ButtonGroup = uibuttongroup(app.GridLayout4);
            app.CykloidnButton = uiradiobutton(app.ButtonGroup);
            app.CykloidnButton.Text = 'Cykloidné';
            app.EvolventnButton = uiradiobutton(app.ButtonGroup);
            app.EvolventnButton.Text = 'Evolventné';
            app.EvolventnButton.Value = true;
            app.DodatoneParametreButton = uibutton(app.GridLayout4, 'push');
            app.DodatoneParametreButton.ButtonPushedFcn = createCallbackFcn(app, @DodatoneParametreButtonPushed, true);
            app.DodatoneParametreButton.Text = 'Dodatočné parametre';
            app.GridLayout5 = uigridlayout(app.GridLayout);
            app.Option2SymbolicLabel = uilabel(app.GridLayout5);
            app.Option2SymbolicLabel.Enable = 'off';
            app.Option2SymbolicLabel.Visible = 'off';
            app.Option2SymbolicLabel.Interpreter = 'latex';
            app.Option2SymbolicLabel.Text = '$\rho_h  \: \mathrm{[mm]}$';
            app.Option2Label = uilabel(app.GridLayout5);
            app.Option2Label.Enable = 'off';
            app.Option2Label.Visible = 'off';
            app.Option2Label.Text = 'Polomer tvoriacej kružnice hypocykloidy';
            app.Option1SymbolicLabel = uilabel(app.GridLayout5);
            app.Option1SymbolicLabel.Interpreter = 'latex';
            app.Option1SymbolicLabel.Text = '$\alpha \: \mathrm{[^\circ]}$';
            app.Option1Label = uilabel(app.GridLayout5);
            app.Option1Label.Text = 'Uhol profilu';
            app.ProfileShiftCoefficientSymbolicLabel = uilabel(app.GridLayout5);
            app.ProfileShiftCoefficientSymbolicLabel.Interpreter = 'latex';
            app.ProfileShiftCoefficientSymbolicLabel.Text = '$x \: \mathrm{[-]}$';
            app.ProfileShiftCoefficientLabel = uilabel(app.GridLayout5);
            app.ProfileShiftCoefficientLabel.Text = 'Jednotkové posunutie';
            app.NumberOfTeethSymbolicLabel = uilabel(app.GridLayout5);
            app.NumberOfTeethSymbolicLabel.Interpreter = 'latex';
            app.NumberOfTeethSymbolicLabel.Text = '$z \: \mathrm{[-]}$';
            app.NumberOfTeethLabel = uilabel(app.GridLayout5);
            app.NumberOfTeethLabel.Text = 'Počet zubov';
            app.ModuleSymbolicLabel = uilabel(app.GridLayout5);
            app.ModuleSymbolicLabel.Interpreter = 'latex';
            app.ModuleSymbolicLabel.Text = '$m \: \mathrm{[mm]}$';
            app.ModuleLabel = uilabel(app.GridLayout5);
            app.ModuleLabel.Text = 'Modul ozubenia';
            app.ParameterChoiceButtonGroup = uibuttongroup(app.GridLayout5);
            app.ParameterChoiceButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ParameterChoiceButtonGroupSelectionChanged, true);
            app.Option2Button = uiradiobutton(app.ParameterChoiceButtonGroup);
            app.Option2Button.Enable = 'off';
            app.Option2Button.Visible = 'off';
            app.Option2Button.Text = '';
            app.Option1Button = uiradiobutton(app.ParameterChoiceButtonGroup);
            app.Option1Button.Text = '';
            app.ProfileShiftCoefficientButton = uiradiobutton(app.ParameterChoiceButtonGroup);
            app.ProfileShiftCoefficientButton.Text = '';
            app.NumberOfTeethButton = uiradiobutton(app.ParameterChoiceButtonGroup);
            app.NumberOfTeethButton.Text = '';
            app.ModuleButton = uiradiobutton(app.ParameterChoiceButtonGroup);
            app.ModuleButton.Text = '';
            app.ModuleButton.Value = true;
            app.Option2EditField_2 = uieditfield(app.GridLayout5, 'numeric');
            app.Option2EditField_2.LowerLimitInclusive = 'off';
            app.Option2EditField_2.UpperLimitInclusive = 'off';
            app.Option2EditField_2.Limits = [0 Inf];
            app.Option2EditField_2.AllowEmpty = 'on';
            app.Option2EditField_2.Enable = 'off';
            app.Option2EditField_2.Visible = 'off';
            app.Option2EditField_2.Value = [];
            app.Option2EditField_1 = uieditfield(app.GridLayout5, 'numeric');
            app.Option2EditField_1.LowerLimitInclusive = 'off';
            app.Option2EditField_1.UpperLimitInclusive = 'off';
            app.Option2EditField_1.AllowEmpty = 'on';
            app.Option2EditField_1.Enable = 'off';
            app.Option2EditField_1.Visible = 'off';
            app.Option2EditField_1.Value = 10;
            app.Option1EditField_2 = uieditfield(app.GridLayout5, 'numeric');
            app.Option1EditField_2.LowerLimitInclusive = 'off';
            app.Option1EditField_2.AllowEmpty = 'on';
            app.Option1EditField_2.ValueChangedFcn = createCallbackFcn(app, @Option1EditField_2ValueChanged, true);
            app.Option1EditField_2.Value = [];
            app.Option1EditField_1 = uieditfield(app.GridLayout5, 'numeric');
            app.Option1EditField_1.LowerLimitInclusive = 'off';
            app.Option1EditField_1.UpperLimitInclusive = 'off';
            app.Option1EditField_1.Limits = [0 Inf];
            app.Option1EditField_1.ValueChangedFcn = createCallbackFcn(app, @Option1EditField_1ValueChanged, true);
            app.Option1EditField_1.Value = 20;
            app.ProfileShiftCoefficientEditField_2 = uieditfield(app.GridLayout5, 'numeric');
            app.ProfileShiftCoefficientEditField_2.LowerLimitInclusive = 'off';
            app.ProfileShiftCoefficientEditField_2.AllowEmpty = 'on';
            app.ProfileShiftCoefficientEditField_2.ValueChangedFcn = createCallbackFcn(app, @ProfileShiftCoefficientEditField_2ValueChanged, true);
            app.ProfileShiftCoefficientEditField_2.Value = [];
            app.ProfileShiftCoefficientEditField_1 = uieditfield(app.GridLayout5, 'numeric');
            app.ProfileShiftCoefficientEditField_1.LowerLimitInclusive = 'off';
            app.ProfileShiftCoefficientEditField_1.UpperLimitInclusive = 'off';
            app.ProfileShiftCoefficientEditField_1.ValueChangedFcn = createCallbackFcn(app, @ProfileShiftCoefficientEditField_1ValueChanged, true);
            app.NumberOfTeethEditField_2 = uieditfield(app.GridLayout5, 'numeric');
            app.NumberOfTeethEditField_2.LowerLimitInclusive = 'off';
            app.NumberOfTeethEditField_2.AllowEmpty = 'on';
            app.NumberOfTeethEditField_2.ValueChangedFcn = createCallbackFcn(app, @NumberOfTeethEditField_2ValueChanged, true);
            app.NumberOfTeethEditField_2.Value = [];
            app.NumberOfTeethEditField_1 = uieditfield(app.GridLayout5, 'numeric');
            app.NumberOfTeethEditField_1.LowerLimitInclusive = 'off';
            app.NumberOfTeethEditField_1.UpperLimitInclusive = 'off';
            app.NumberOfTeethEditField_1.Limits = [0 Inf];
            app.NumberOfTeethEditField_1.ValueChangedFcn = createCallbackFcn(app, @NumberOfTeethEditField_1ValueChanged, true);
            app.NumberOfTeethEditField_1.Value = 20;
            app.ModuleEditField_2 = uieditfield(app.GridLayout5, 'numeric');
            app.ModuleEditField_2.LowerLimitInclusive = 'off';
            app.ModuleEditField_2.AllowEmpty = 'on';
            app.ModuleEditField_2.ValueChangedFcn = createCallbackFcn(app, @ModuleEditField_2ValueChanged, true);
            app.ModuleEditField_2.Value = [];
            app.ModuleEditField_1 = uieditfield(app.GridLayout5, 'numeric');
            app.ModuleEditField_1.LowerLimitInclusive = 'off';
            app.ModuleEditField_1.UpperLimitInclusive = 'off';
            app.ModuleEditField_1.Limits = [0 Inf];
            app.ModuleEditField_1.ValueChangedFcn = createCallbackFcn(app, @ModuleEditField_1ValueChanged, true);
            app.ModuleEditField_1.Value = 1;
            app.NastaveniezobrazeniaPanel = uipanel(app.ProfileGridLayout);
            app.NastaveniezobrazeniaPanel.Title = 'Nastavenie zobrazenia';
            app.GridLayout7 = uigridlayout(app.NastaveniezobrazeniaPanel);
            app.GridLayout2 = uigridlayout(app.GridLayout7);
            app.MonostigenerovaniaLabel = uilabel(app.GridLayout2);
            app.MonostigenerovaniaLabel.Text = 'Možnosti generovania:';
            app.GridLayout3 = uigridlayout(app.GridLayout2);
            app.NumberOfProfilesSpinnerLabel = uilabel(app.GridLayout3);
            app.NumberOfProfilesSpinnerLabel.Text = 'Počet:';
            app.NumberOfProfilesSpinner = uispinner(app.GridLayout3);
            app.NumberOfProfilesSpinner.Limits = [2 Inf];
            app.NumberOfProfilesSpinner.Value = 10;
            app.ButtonGroup_2 = uibuttongroup(app.GridLayout2);
            app.ButtonGroup_2.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroup_2SelectionChanged, true);
            app.ParametricksekvenciaprofilovButton = uiradiobutton(app.ButtonGroup_2);
            app.ParametricksekvenciaprofilovButton.Text = 'Parametrická sekvencia profilov';
            app.JednotlivprofilButton = uiradiobutton(app.ButtonGroup_2);
            app.JednotlivprofilButton.Text = 'Jednotlivý profil';
            app.JednotlivprofilButton.Value = true;
            app.GridLayout8 = uigridlayout(app.GridLayout7);
            app.UzamknutiezobrazeniadopoiatkuLabel = uilabel(app.GridLayout8);
            app.UzamknutiezobrazeniadopoiatkuLabel.Text = 'Uzamknutie zobrazenia do počiatku:';
            app.LockButton = uibutton(app.GridLayout8, 'push');
            app.LockButton.ButtonPushedFcn = createCallbackFcn(app, @LockButtonPushed, true);
            app.LockButton.Icon = fullfile(pathToMLAPP, 'Images', 'Lock_open.png');
            app.LockButton.Text = '';
            app.GridLayout14 = uigridlayout(app.GridLayout7);
            app.GrafLabel = uilabel(app.GridLayout14);
            app.GrafLabel.Text = 'Graf:';
            app.iaryLabel = uilabel(app.GridLayout14);
            app.iaryLabel.Text = 'Čiary:';
            app.GridLayout18 = uigridlayout(app.GridLayout14);
            app.tlDropDownLabel = uilabel(app.GridLayout18);
            app.tlDropDownLabel.Text = 'Štýl';
            app.tlDropDown = uidropdown(app.GridLayout18);
            app.tlDropDown.Items = {'Plná', 'Prerušovaná', 'Bodkovaná', 'Bodkočiarkovaná'};
            app.tlDropDown.ValueChangedFcn = createCallbackFcn(app, @tlDropDownValueChanged, true);
            app.tlDropDown.Value = 'Plná';
            app.GridLayout17 = uigridlayout(app.GridLayout14);
            app.FarbaColorPickerLabel = uilabel(app.GridLayout17);
            app.FarbaColorPickerLabel.Enable = 'off';
            app.FarbaColorPickerLabel.Text = 'Farba';
            app.FarbaColorPicker = uicolorpicker(app.GridLayout17);
            app.FarbaColorPicker.Value = [0 0 0];
            app.FarbaColorPicker.Icon = 'line';
            app.FarbaColorPicker.ValueChangedFcn = createCallbackFcn(app, @FarbaColorPickerValueChanged, true);
            app.FarbaColorPicker.Enable = 'off';
            app.GridLayout16 = uigridlayout(app.GridLayout14);
            app.HrbkaSpinnerLabel = uilabel(app.GridLayout16);
            app.HrbkaSpinnerLabel.Text = 'Hrúbka';
            app.HrbkaSpinner = uispinner(app.GridLayout16);
            app.HrbkaSpinner.Step = 0.05;
            app.HrbkaSpinner.LowerLimitInclusive = 'off';
            app.HrbkaSpinner.Limits = [0 Inf];
            app.HrbkaSpinner.ValueChangedFcn = createCallbackFcn(app, @HrbkaSpinnerValueChanged, true);
            app.HrbkaSpinner.Value = 1;
            app.MriekaCheckBox = uicheckbox(app.GridLayout14);
            app.MriekaCheckBox.ValueChangedFcn = createCallbackFcn(app, @MriekaCheckBoxValueChanged, true);
            app.MriekaCheckBox.Text = 'Mriežka';
            app.MriekaCheckBox.Value = true;
            app.OsiCheckBox = uicheckbox(app.GridLayout14);
            app.OsiCheckBox.ValueChangedFcn = createCallbackFcn(app, @OsiCheckBoxValueChanged, true);
            app.OsiCheckBox.Text = 'Osi';
            app.OsiCheckBox.Value = true;
            app.VznamnkruniceButton = uibutton(app.GridLayout14, 'push');
            app.VznamnkruniceButton.ButtonPushedFcn = createCallbackFcn(app, @VznamnkruniceButtonPushed, true);
            app.VznamnkruniceButton.Text = 'Významné kružnice';
            app.GridLayout6 = uigridlayout(app.ProfileGridLayout);
            app.DrawProfileButton = uibutton(app.GridLayout6, 'push');
            app.DrawProfileButton.ButtonPushedFcn = createCallbackFcn(app, @DrawProfileButtonPushed, true);
            app.DrawProfileButton.Text = 'ZOBRAZIŤ';
            app.CancelProfileButton = uibutton(app.GridLayout6, 'push');
            app.CancelProfileButton.ButtonPushedFcn = createCallbackFcn(app, @CancelProfileButtonPushed, true);
            app.CancelProfileButton.Text = 'ZMAZAŤ POSLEDNÝ';
            app.CancelAllProfilesButton = uibutton(app.GridLayout6, 'push');
            app.CancelAllProfilesButton.ButtonPushedFcn = createCallbackFcn(app, @CancelAllProfilesButtonPushed, true);
            app.CancelAllProfilesButton.Text = 'ZMAZAŤ VŠETKO';
            app.ExportPanel = uipanel(app.ProfileGridLayout);
            app.ExportPanel.Title = 'Exportovanie';
            app.ExportGridLayout = uigridlayout(app.ExportPanel);
            app.AnimationTab = uitab(app.MainTabGroup);
            app.AnimationGridLayout = uigridlayout(app.AnimationTab);
            app.ExportSettingPanel = uipanel(app.AnimationGridLayout);
            app.ExportSettingPanel.Title = 'Panel3';
            app.ToothingSettingPanel = uipanel(app.AnimationGridLayout);
            app.ToothingSettingPanel.Title = 'Panel2';
            app.AnimationSettingPanel = uipanel(app.AnimationGridLayout);
            app.AnimationSettingPanel.Title = 'Panel';
            app.AnimationSettingGridLayout = uigridlayout(app.AnimationSettingPanel);
            app.UpperAnimationSettingGridLayout = uigridlayout(app.AnimationSettingGridLayout);
            app.LowerAnimationSettingGridLayout = uigridlayout(app.AnimationSettingGridLayout);
            app.DisplayButtonsGridLayout = uigridlayout(app.AnimationGridLayout);
            app.CancelButton = uibutton(app.DisplayButtonsGridLayout, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelAllProfilesButtonPushed, true);
            app.CancelButton.Text = 'Button3';
            app.DisplayButton = uibutton(app.DisplayButtonsGridLayout, 'push');
            app.DisplayButton.ButtonPushedFcn = createCallbackFcn(app, @DisplayButtonPushed, true);
            app.StartPauseButton = uibutton(app.DisplayButtonsGridLayout, 'push');
            app.StartPauseButton.ButtonPushedFcn = createCallbackFcn(app, @StartPauseButtonPushed, true);
            app.StartPauseButton.Enable = 'off';
            app.StartPauseButton.Text = 'Button2';
            % Show the figure after all components are created
            app.MainUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Gear_model

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MainUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MainUIFigure)
        end
    end
end